classdef ImageRefinerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ImportSettings               matlab.ui.container.Panel
        ChaneltoSelectDropDownLabel  matlab.ui.control.Label
        ChanneltoSelectDropDown      matlab.ui.control.DropDown
        ImportPathEditFieldLabel     matlab.ui.control.Label
        ImportPathEditField          matlab.ui.control.EditField
        AppStatusTextAreaLabel       matlab.ui.control.Label
        AppStatusTextArea            matlab.ui.control.TextArea
        RunRefinement                matlab.ui.container.Panel
        RunRefinementButton          matlab.ui.control.StateButton
        RunDirectoryCrawlerButton    matlab.ui.control.StateButton
        RunExportButton              matlab.ui.control.StateButton
        StartupStateButton           matlab.ui.control.StateButton
        UITable                      matlab.ui.control.Table
        ExportSettings               matlab.ui.container.Panel
        ExportPathEditFieldLabel     matlab.ui.control.Label
        ExportPathEditField          matlab.ui.control.EditField
        ExportFormatListBoxLabel     matlab.ui.control.Label
        ExportFormatListBox          matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        ExportPath
        ImportPath
        ChannelToSelect
        ImageExtension
        StatusEnum
        ImageDtoCollection
        SummaryCollection
    end


    methods (Access = private)
        
        function lockNotApplicableTasks(app)
            statusEnum = app.StatusEnum;
        end
        
        function app = initializeApp(app)
            app.ExportPath = Settings.EXPORT_PATH;
            app.ImportPath = Settings.IMPORT_PATH;
            app.ChannelToSelect = Settings.COLOR_CHANEL_ENUM.toString();
            app.ImageExtension = Settings.IMPORT_IMAGE_EXTENSION;
            app.StatusEnum = Enum.StatusEnum.Startup;
            
            app.lockNotApplicableTasks();
        end
        
        function runDirectoryCrawler(app)
            directoryCrawler = ImageHandler.DirectoryCrawler(app.ImportPath, app.ExportPath);
            directoryCrawler.execute();
            
            app.ImageDtoCollection = directoryCrawler.ImageDtoCollection;
            app.StatusEnum = Enum.StatusEnum.DirectoryCrawler;
            app.lockNotApplicableTasks();
        end
        
        function runImageAnalyzer(app)
            imageAnalyzer = ImageHandler.ImageAnalyzer(app.ImageDtoCollection);
            imageAnalyzer.execute;
            
            app.SummaryCollection = imageAnalyzer.SummaryCollection;
            app.StatusEnum = Enum.StatusEnum.ImageAnalyzer;
            app.lockNotApplicableTasks();
        end
        
        function runExporter(app)
            excelExporter = Export.ExcelExporter(app.ExportDirectory,...
                    app.ImageDtoCollection, app.SummaryCollection);
            excelExporter.execute;
            
            app.StatusEnum = Enum.StatusEnum.Exporter;
            app.lockNotApplicableTasks();
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app = app.initializeApp();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportSettings = uipanel(app.UIFigure);
            app.ImportSettings.Title = 'Settings Import';
            app.ChaneltoSelectDropDownLabel = uilabel(app.ImportSettings);
            app.ChaneltoSelectDropDownLabel.Text = 'Chanel to Select';
            app.ChanneltoSelectDropDown = uidropdown(app.ImportSettings);
            app.ChanneltoSelectDropDown.Items = {'BLUE', 'GREEN', 'RED', 'OVERLAY'};
            app.ChanneltoSelectDropDown.Value = 'BLUE';
            app.ImportPathEditFieldLabel = uilabel(app.ImportSettings);
            app.ImportPathEditFieldLabel.Text = 'Import Path';
            app.ImportPathEditField = uieditfield(app.ImportSettings, 'text');
            app.AppStatusTextAreaLabel = uilabel(app.UIFigure);
            app.AppStatusTextAreaLabel.Text = 'App Status';
            app.AppStatusTextArea = uitextarea(app.UIFigure);
            app.RunRefinement = uipanel(app.UIFigure);
            app.RunRefinement.Title = 'Run Refinement';
            app.RunRefinementButton = uibutton(app.RunRefinement, 'state');
            app.RunRefinementButton.Text = 'Run Refinement';
            app.RunDirectoryCrawlerButton = uibutton(app.RunRefinement, 'state');
            app.RunDirectoryCrawlerButton.Text = 'Run Directory Crawler';
            app.RunExportButton = uibutton(app.RunRefinement, 'state');
            app.RunExportButton.Text = 'Run Export';
            app.StartupStateButton = uibutton(app.RunRefinement, 'state');
            app.StartupStateButton.Text = 'Startup State';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ExportSettings = uipanel(app.UIFigure);
            app.ExportSettings.Title = 'Panel2';
            app.ExportPathEditFieldLabel = uilabel(app.ExportSettings);
            app.ExportPathEditFieldLabel.Text = 'Export Path';
            app.ExportPathEditField = uieditfield(app.ExportSettings, 'text');
            app.ExportFormatListBoxLabel = uilabel(app.ExportSettings);
            app.ExportFormatListBoxLabel.Text = 'Export Format';
            app.ExportFormatListBox = uilistbox(app.ExportSettings);
            app.ExportFormatListBox.Items = {'Excel', 'Json', ''};
            app.ExportFormatListBox.Value = 'Excel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageRefinerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end